classdef cardgame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        WINNEREditField           matlab.ui.control.EditField
        WINNEREditFieldLabel      matlab.ui.control.Label
        dealersnumberEditField    matlab.ui.control.NumericEditField
        dealersnumberLabel        matlab.ui.control.Label
        yournumberEditField       matlab.ui.control.NumericEditField
        yournumberLabel           matlab.ui.control.Label
        Image10                   matlab.ui.control.Image
        Image9                    matlab.ui.control.Image
        dealersbetEditField       matlab.ui.control.EditField
        dealersbetEditFieldLabel  matlab.ui.control.Label
        YourbetEditField          matlab.ui.control.EditField
        YourbetEditFieldLabel     matlab.ui.control.Label
        cardsyouhaveLabel         matlab.ui.control.Label
        Image8                    matlab.ui.control.Image
        Image7                    matlab.ui.control.Image
        Image6                    matlab.ui.control.Image
        Image5                    matlab.ui.control.Image
        Image4                    matlab.ui.control.Image
        Image3                    matlab.ui.control.Image
        Image2                    matlab.ui.control.Image
        standButton               matlab.ui.control.Button
        callButton                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: YourbetEditField
        function YourbetEditFieldValueChanged(app, event)
            value = app.YourbetEditField.Value;
            
        end

        % Button pushed function: callButton
        function callButtonPushed(app, event)
            
        end

        % Image clicked function: Image5
        function Image5Clicked(app, event)
            imageArray= ["2 Small.png", "3 Small.png" ,"4 Small.png","1 Small.png" ,"5 Small.png","8 Small.png" ,"9 Small.png" ,"10 Small.png" ,"11 Small.png" ,"14 Small.png" ,"15 Small.png" ,"16 Small.png" ,"17 Small.png" ,"20 Small.png" ,"21 Small.png","22 Small.png" ,"23 Small.png" ,"26 Small.png" ,"27 Small.png" ,"28 Small.png" ,"29 Small.png" ,"32 Small.png" ,"33 Small.png" ,"34 Small.png" ,"35 Small.png" ,"38 Small.png" ,"39 Small.png" ,"40 Small.png" ,"41 Small.png" ,"44 Small.png" ,"45 Small.png" ,"46 Small.png" ,"47 Small.png" ,"50 Small.png" ,"51 Small.png" ,"52 Small.png"
           "6 Small.png" "7 Small.png" "12 Small.png" "13 Small.png" "18 Small.png" "19 Small.png" "24 Small.png" "25 Small.png" "30 Small.png" "31 Small.png" "36 Small.png" "37 Small.png""42 Small.png","43 Small.png","48 Small.png","49 Small.png"];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.callButton = uibutton(app.UIFigure, 'push');
            app.callButton.ButtonPushedFcn = createCallbackFcn(app, @callButtonPushed, true);
            app.callButton.Text = 'call';
            app.standButton = uibutton(app.UIFigure, 'push');
            app.standButton.Text = 'stand';
            app.Image2 = uiimage(app.UIFigure);
            app.Image3 = uiimage(app.UIFigure);
            app.Image4 = uiimage(app.UIFigure);
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image5Clicked, true);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Pictures', '0.gif');
            app.Image6 = uiimage(app.UIFigure);
            app.Image7 = uiimage(app.UIFigure);
            app.Image8 = uiimage(app.UIFigure);
            app.cardsyouhaveLabel = uilabel(app.UIFigure);
            app.cardsyouhaveLabel.Text = 'cards you have:';
            app.YourbetEditFieldLabel = uilabel(app.UIFigure);
            app.YourbetEditFieldLabel.Text = 'Your bet:';
            app.YourbetEditField = uieditfield(app.UIFigure, 'text');
            app.YourbetEditField.ValueChangedFcn = createCallbackFcn(app, @YourbetEditFieldValueChanged, true);
            app.YourbetEditField.Value = '0';
            app.dealersbetEditFieldLabel = uilabel(app.UIFigure);
            app.dealersbetEditFieldLabel.Text = 'dealer''s bet:';
            app.dealersbetEditField = uieditfield(app.UIFigure, 'text');
            app.dealersbetEditField.Value = '0';
            app.Image9 = uiimage(app.UIFigure);
            app.Image10 = uiimage(app.UIFigure);
            app.yournumberLabel = uilabel(app.UIFigure);
            app.yournumberLabel.Text = 'your number:';
            app.yournumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.dealersnumberLabel = uilabel(app.UIFigure);
            app.dealersnumberLabel.Text = 'dealer''s number:';
            app.dealersnumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.WINNEREditFieldLabel = uilabel(app.UIFigure);
            app.WINNEREditFieldLabel.Text = 'WINNER:';
            app.WINNEREditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cardgame

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end